classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EditField_3                  matlab.ui.control.EditField
        Player2ScoreEditField_2      matlab.ui.control.EditField
        BankButton                   matlab.ui.control.Button
        EditField_2                  matlab.ui.control.EditField
        EditField                    matlab.ui.control.EditField
        Player2ScoreEditField        matlab.ui.control.EditField
        Player1ScoreEditField        matlab.ui.control.EditField
        AddComboButton               matlab.ui.control.Button
        EnterDataButton              matlab.ui.control.Button
        PlayerEntryEditField         matlab.ui.control.EditField
        PlayerEntryEditFieldLabel    matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        CurrentPromptEditField       matlab.ui.control.EditField
        CurrentPromptEditFieldLabel  matlab.ui.control.Label
        RollButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        rollData % Description
        player1Score
        player2Score
        player1Name
        player2Name
        currentPointsEarned
        pointsAdded
        isPlayer1Turn
        winCon
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RollButton
        function RollButtonPushed(app, event)
            tableNames = ['Dice 1';'Dice 2';'Dice 3';'Dice 4';'Dice 5';'Dice 6'];
            
            fprintf("length of roll data below:");
            length(app.rollData)

            app.rollData = rollDice(length(app.rollData));

            newfig = app.UIFigure;
            uitable(newfig,"Data",app.rollData,"ColumnName",tableNames,"Units",'normalized',"Position",[0,0.9,1,0.1]);
            if(isFarkle(app.rollData))
                if(app.isPlayer1Turn)
                    app.player1Score = app.player1Score + app.currentPointsEarned;
                    app.Player1ScoreEditField.Value = string(app.player1Score);
                    app.CurrentPromptEditField.Value = strcat("It is now ", app.player2Name, "'s turn.");
                else
                    app.player2Score = app.player2Score + app.currentPointsEarned;
                    app.Player2ScoreEditField.Value = string(app.player2Score);
                    app.CurrentPromptEditField.Value = strcat("It is now ", app.player1Name, "'s turn.");
                end
                app.rollData = [0,0,0,0,0,0];
                app.isPlayer1Turn = ~app.isPlayer1Turn;
            end
        end

        % Value changed function: CurrentPromptEditField
        function CurrentPromptEditFieldValueChanged(app, event)
            value = app.CurrentPromptEditField.Value;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.CurrentPromptEditField.Value = "Please enter a name for player 1.";
            app.isPlayer1Turn = true;
            app.player1Score = 0;
            app.player2Score = 0;
            app.winCon = 10000;
            app.Player2ScoreEditField_2.Value = string(0);
            app.rollData = [0,0,0,0,0,0];
            %app.CurrentPromptEditField.Value = string(length(app.rollData));
            tableNames = ['Dice 1';'Dice 2';'Dice 3';'Dice 4';'Dice 5';'Dice 6'];
            newfig = app.UIFigure;
            uitable(newfig,"Data",app.rollData,"ColumnName",tableNames,"Units",'normalized',"Position",[0,0.9,1,0.1]);
        end

        % Button pushed function: EnterDataButton
        function EnterDataButtonPushed(app, event)
            switch(app.CurrentPromptEditField.Value)
                case("Please enter a name for player 1.")
                    app.player1Name = app.PlayerEntryEditField.Value;
                    app.EditField.Value = app.player1Name;
                    app.PlayerEntryEditField.Value = "";
                    app.CurrentPromptEditField.Value = "Please enter a name for player 2.";
                case("Please enter a name for player 2.")
                    app.player2Name = app.PlayerEntryEditField.Value;
                    app.EditField_2.Value = app.player2Name;
                    app.PlayerEntryEditField.Value = "";
            end
        end

        % Value changed function: PlayerEntryEditField
        function PlayerEntryEditFieldValueChanged(app, event)
            value = app.PlayerEntryEditField.Value;
            
        end

        % Button pushed function: AddComboButton
        function AddComboButtonPushed(app, event)
            
            [app.pointsAdded, app.rollData] = bank(str2num(app.PlayerEntryEditField.Value),app.rollData);
            fprintf("Remaining array below:")
            app.rollData
            app.currentPointsEarned = app.pointsAdded + app.currentPointsEarned;
            app.Player2ScoreEditField_2.Value = string(app.currentPointsEarned);

        end

        % Button pushed function: BankButton
        function BankButtonPushed(app, event)
            app.Player2ScoreEditField_2.Value = string(0);
            if(app.isPlayer1Turn)
                app.player1Score = app.player1Score + app.currentPointsEarned;
                app.Player1ScoreEditField.Value = string(app.player1Score);
                if(app.player1Score >= app.winCon)
                    app.CurrentPromptEditField.Value = strcat(app.player1Name, " has won the game.");
                    StartButtonPushed(app, event);
                else
                    app.CurrentPromptEditField.Value = strcat("It is now ", app.player2Name, "'s turn.");
                end
            else
                app.player2Score = app.player2Score + app.currentPointsEarned;
                app.Player2ScoreEditField.Value = string(app.player2Score);
                app.CurrentPromptEditField.Value = strcat("It is now ", app.player1Name, "'s turn.");
            end
            app.rollData = [0,0,0,0,0,0];
            app.isPlayer1Turn = ~app.isPlayer1Turn;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RollButton = uibutton(app.UIFigure, 'push');
            app.RollButton.ButtonPushedFcn = createCallbackFcn(app, @RollButtonPushed, true);
            app.RollButton.Text = 'Roll';
            app.CurrentPromptEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentPromptEditFieldLabel.Text = 'Current Prompt';
            app.CurrentPromptEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentPromptEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentPromptEditFieldValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PlayerEntryEditFieldLabel = uilabel(app.UIFigure);
            app.PlayerEntryEditFieldLabel.Text = 'Player Entry';
            app.PlayerEntryEditField = uieditfield(app.UIFigure, 'text');
            app.PlayerEntryEditField.ValueChangedFcn = createCallbackFcn(app, @PlayerEntryEditFieldValueChanged, true);
            app.EnterDataButton = uibutton(app.UIFigure, 'push');
            app.EnterDataButton.ButtonPushedFcn = createCallbackFcn(app, @EnterDataButtonPushed, true);
            app.EnterDataButton.Text = 'Enter Data';
            app.AddComboButton = uibutton(app.UIFigure, 'push');
            app.AddComboButton.ButtonPushedFcn = createCallbackFcn(app, @AddComboButtonPushed, true);
            app.AddComboButton.Text = 'Add Combo';
            app.Player1ScoreEditField = uieditfield(app.UIFigure, 'text');
            app.Player2ScoreEditField = uieditfield(app.UIFigure, 'text');
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.BankButton = uibutton(app.UIFigure, 'push');
            app.BankButton.ButtonPushedFcn = createCallbackFcn(app, @BankButtonPushed, true);
            app.BankButton.Text = 'Bank';
            app.Player2ScoreEditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Value = 'Points Ready to Bank';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end